classdef matlabwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileOperator              matlab.ui.container.Menu
        newFile                   matlab.ui.container.Menu
        openFile                  matlab.ui.container.Menu
        saveFile                  matlab.ui.container.Menu
        saveOtherFile             matlab.ui.container.Menu
        Exit                      matlab.ui.container.Menu
        FilterOperator            matlab.ui.container.Menu
        gaussian                  matlab.ui.container.Menu
        edgeOperator              matlab.ui.container.Menu
        CannyMenu                 matlab.ui.container.Menu
        SobelMenu                 matlab.ui.container.Menu
        LaplaceMenu               matlab.ui.container.Menu
        filteringOperator         matlab.ui.container.Menu
        medianFilter              matlab.ui.container.Menu
        averFilter                matlab.ui.container.Menu
        FFTOperator               matlab.ui.container.Menu
        FFT_IFFTH                 matlab.ui.container.Menu
        FFT_IFFTL                 matlab.ui.container.Menu
        expandOperator            matlab.ui.container.Menu
        interpm                   matlab.ui.container.Menu
        bicubit                   matlab.ui.container.Menu
        picPlus                   matlab.ui.container.Menu
        balancePic                matlab.ui.container.Menu
        ColorOperator             matlab.ui.container.Menu
        rgb2gray_first            matlab.ui.container.Menu
        rgb2gray_1                matlab.ui.container.Menu
        rgb2gray_2                matlab.ui.container.Menu
        rgb2gray_3                matlab.ui.container.Menu
        rgb2gray_4                matlab.ui.container.Menu
        operatorHistory           matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        infoMessageTextArea       matlab.ui.control.TextArea
        infoMessageTextAreaLabel  matlab.ui.control.Label
        imgShow                   matlab.ui.control.UIAxes
    end

    %属性
    properties (Access = public)
        selectPic %用户选择的图片
        selectPath %图片路径
        history %存储操作过变量
        historyDivi %刻度尺
        index %操作记录
        tempPic %操作图片temp
    end
    methods (Access = public)
    
    %灰度转换-平均值法
    function afterOrigin = rgb2gray1(~,origin)
			[rows,cols,dim]=size(origin);
            if dim>1
             %说明为彩色图
            afterOrigin=uint8(zeros(rows,cols));
            for i=1:rows
                for j=1:cols
                    sum=0;
                    for k=1:3
                        sum=sum+origin(i,j,k)/3;
                    end
                    afterOrigin(i,j)=sum;
                end
            end
            else
             msgbox("请打开一个彩色图！");
             afterOrigin=[];
            end


     end
    
    %灰度转换-单通道提取法(分量法)
    function afterOrigin = rgb2gray2(~,origin,number)
         [~,~,dim]=size(origin);
         if dim>1
             %说明为彩色图
           switch(number)
             case 1,afterOrigin=origin(:,:,1);
             case 2,afterOrigin=origin(:,:,2);
             case 3,afterOrigin=origin(:,:,3);
           end
         else
             msgbox("请打开一个彩色图！");
             afterOrigin=[];
         end
         

    end
    
    %灰度转换-最大值法
    function afterOrigin = rgb2gray3(~,origin)
        [rows,cols,dim]=size(origin);
        if dim>1
         afterOrigin=uint8(zeros(rows,cols));
         for i=1:rows
            for j=1:cols
                r=origin(i,j,1);
                g=origin(i,j,2);
                b=origin(i,j,3);
                tempMax=r;
                if tempMax<g
                    tempMax=g;
                elseif tempMax<b
                    tempMax=b;
                end
                    afterOrigin(i,j)=tempMax;
             end
         end
        else
             msgbox("请打开一个彩色图！");
             afterOrigin=[];
        end
    
    end
    
    %灰度转换-加权平均值法  
    function afterOrigin = rgb2gray4(~,origin)
            [~,~,dim]=size(origin);
            if dim>1
            r=origin(:,:,1);
		    g=origin(:,:,2);
		    b=origin(:,:,3);
		    [row,col,~]=size(origin);
            afterOrigin=uint8(zeros(row,col));
            for ro=1:row
                for co=1:col
                    afterOrigin(ro,co)=r(ro,co)*0.299 + g(ro,co)*0.587 + b(ro,co)*0.114;
                end
            end
            else
             msgbox("请打开一个彩色图！");
             afterOrigin=[];
            end

    end
    
    %高斯模糊 afterGaussian 返回Canny边缘图  origin 传递灰度图片
    function afterGaussian = MyGaussian(~,origin,N)
        [rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		%创建3*3高斯矩阵
		%N=3;
        N=round(N);
		toolcore=zeros(N);
		de=10.0;
		for x=1:N
			for y=1:N
				%公式
				toolcore(x,y)=(exp((-((x)^2+(y)^2))/(2*de^2)) )/2*pi*de^2;
			end
		end
		%保证相加为1
		toolcore=toolcore./(sum(sum(toolcore)));
		%扩展
		expandNumber=floor(N/2);
		expand_img = double(wextend('2D','zpd',origin,expandNumber));
        afterGaussian=uint8(zeros(rows,cols));
        for i=1:rows
            for j=1:cols
                area=expand_img(i:i+N-1,j:j+N-1);
                temp=area.*toolcore;
                temp2=sum(sum(temp));
                afterGaussian(i,j)=temp2;
            end
        end
        afterGaussian=uint8(afterGaussian);
    end
   
    %边缘检测-canny算子 afterCanny 返回Canny边缘图 %origin 传递灰度图片
    function afterCanny = MyCanny(~,origin)
        [rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		G = fspecial('gaussian', 5, 2);
		img=im2double(origin); 
		img= imfilter(img,G,'same');
		%计算梯度值
		img_TX=zeros(rows,cols);
		img_TY=zeros(rows,cols);
		for r=2:rows-1
			for c=2:cols-1
				tempX=-img(r-1,  c-1) + img(r-1, c+1 )-2* img(r, c-1 ) +  2* img(r,  c+1)-img(r+1, c-1 ) +  img(r+1,  c+1);
				img_TX(r,c)=abs(tempX);
				tempY=img(r-1,c-1)+2*img(r-1,c ) + img(r-1,c+1 ) -img(r+1,c-1) -2*img(r+1, c) -img(r+1,c+1 );
				img_TY(r,c)=abs(tempY);
			end
		end
		img_sobelAll=img_TX+img_TY;
		%计算梯度方向
		imgST=atan2(img_TY,img_TX);
		imgST = imgST*180/pi;
		for i = 1:rows
			for j = 1:cols
				if((imgST(i,j) >= -22.5) && (imgST(i,j) < 0)||(imgST(i,j) >= 0) && (imgST(i,j) < 22.5) || (imgST(i,j) <= -157.5) && (imgST(i,j) >= -180)||(imgST(i,j) >= 157.5)&&(imgST(i,j) <= 180))
					imgST(i,j) = 0;
				elseif((imgST(i,j) >= 22.5) && (imgST(i,j) < 67.5) || (imgST(i,j) <= -112.5) && (imgST(i,j) > -157.5))
					imgST(i,j) = -45;
				elseif((imgST(i,j) >= 67.5) && (imgST(i,j) < 112.5) || (imgST(i,j) <= -67.5) && (imgST(i,j) > -112.5))
					imgST(i,j) = 90;
				elseif((imgST(i,j) >= 112.5) && (imgST(i,j) < 157.5) || (imgST(i,j) <= -22.5) && (imgST(i,j) > -67.5))
					imgST(i,j) = 45;  
				end
			end
		end
		
		%非极大值抑制图像
		Nms = zeros(rows,cols);
		for i = 2:rows-1
			for j= 2:cols-1
				if (imgST(i,j) == 0 && img_sobelAll(i,j) == max([img_sobelAll(i,j), img_sobelAll(i,j+1), img_sobelAll(i,j-1)]))
					Nms(i,j) = img_sobelAll(i,j);
				elseif (imgST(i,j) == -45 && img_sobelAll(i,j) == max([img_sobelAll(i,j), img_sobelAll(i+1,j-1), img_sobelAll(i-1,j+1)]))
					Nms(i,j) = img_sobelAll(i,j);
				elseif (imgST(i,j) == 90 && img_sobelAll(i,j) == max([img_sobelAll(i,j), img_sobelAll(i+1,j), img_sobelAll(i-1,j)]))
					Nms(i,j) = img_sobelAll(i,j);
				elseif (imgST(i,j) == 45 && img_sobelAll(i,j) == max([img_sobelAll(i,j), img_sobelAll(i+1,j+1), img_sobelAll(i-1,j-1)]))
					Nms(i,j) = img_sobelAll(i,j);
				end
			end
		end
		
		DT = zeros(rows,cols);
		TL = 0.1 * max(max(Nms));
		TH = 0.3 * max(max(Nms));
        for i = 1  : rows
            for j = 1 : cols
                if (Nms(i, j) < TL)
					DT(i,j) = 0;
				elseif (Nms(i, j) > TH)
					DT(i,j) = 1 ;
				elseif ( Nms(i+1,j) < TH || Nms(i-1,j) < TH || Nms(i,j+1) < TH || Nms(i,j-1) < TH || Nms(i-1, j-1) < TH || Nms(i-1, j+1) < TH || Nms(i+1, j+1) < TH || Nms(i+1, j-1) < TH)
					DT(i,j) = 1;
                end
            end
        end
        %afterCanny=DT;二值转灰度
        afterCanny=uint8(DT.*255);
    end
    
    %边缘检测-Soble算子 afterSobel 返回Sobel边缘图 %origin 传递灰度图片 threshold 阈值
    function afterSobel = MySobel(~,origin,threshold)
        	[rows,cols,dim]=size(origin);
            if dim>1
                origin=rgb2gray(origin);
            end
			%转double
			img=im2double(origin);
			%Sobel算子
			img_SobelX=zeros(rows,cols);
			img_SobelY=zeros(rows,cols);
			for r=2:rows-1
				for c=2:cols-1
					tempX=-img(r-1,  c-1) + img(r-1, c+1 )-2* img(r, c-1 ) +  2* img(r,  c+1)-img(r+1, c-1 ) +  img(r+1,  c+1);
					img_SobelX(r,c)=abs(tempX);
					tempY=img(r-1,c-1)+2*img(r-1,c ) + img(r-1,c+1 ) -img(r+1,c-1) -2*img(r+1, c) -img(r+1,c+1 );
					img_SobelY(r,c)=abs(tempY);
				end    
			end
			afterSobel=img_SobelX+img_SobelY;
			%抑制
            for r=1:rows
                for c=1:cols
                    if afterSobel(r,c)>threshold
                        afterSobel(r,c)=1;
                    else
                        afterSobel(r,c)=0;
                    end
                end
            end
            %二值转灰度
            afterSobel=uint8(afterSobel.*255);
    end
    
    %边缘检测-Laplace算子 afterLaplace 返回Laplace边缘图 %origin 传递灰度图片 threshold 阈值
    function afterLaplace = MyLaplace(~,origin,threshold)
            [~,~,dim]=size(origin);
            if dim>1
                origin=rgb2gray(origin);
            end
			originDouble=mat2gray(origin);   %图像矩阵的归一化
			[m,n]=size(originDouble);
			afterLaplace=zeros(m-1,n-1);
			%Laplace算子
            for x=2:m-1 
                for y=2:n-1
                    L=-4*originDouble(x, y) + originDouble(x-1, y) + originDouble(x+1, y) + originDouble(x, y-1) + originDouble(x, y+1);
                    if L > threshold
                        afterLaplace(x,y)=1;  %白
                    else
                        afterLaplace(x,y)=0;    %黑
                    end
                end
            end
            afterLaplace=uint8(afterLaplace.*255);
   end
    
    %中值滤波 afterMidFilter 返回中值滤波后的图
    function afterMidFilter = MyMedianFilter(~,origin)
        [rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		N=5;
		afterMidFilter=zeros(rows,cols);
		temp=floor(N/2);
		expand_img = wextend('2D','zpd', origin, temp);%扩展0
        for i=1:rows
            for j=1:cols 
                selectArea=expand_img(i:i+N-1,j:j+N-1);
				%转列
				temp1=selectArea(:);
				middle=median(temp1);
				afterMidFilter(i,j) = middle;
            end
        end
        
    end
    
    %均值滤波 afterAverFilter 返回均值滤波后的图
    function afterAverFilter = MyAverFilter(~,origin)
        [rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		N=4;
		afterAverFilter=zeros(rows,cols);
		muban=ones(N,N);
		muban=(1/(N*N)).*muban;
		expand_number=floor(N/2);
		expand_img = double(wextend('2D','zpd', origin, expand_number));
		for i=1:rows
			for j=1:cols
				ave = sum(   sum( expand_img(i:i+N-1,j:j+N-1) .* muban)   ); 
				afterAverFilter(i,j) = ave;
			end
		end
    end
    
    %直方图均衡化 afterLaplace 返回增强后的图
    function afterBalancePic = MyBalancePic(~,origin)
		[rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		%灰度级
		L=256;
		statNumber=zeros(1,256);
		%转一列
		allNumber=double(origin(:)); 
		pixaccount=rows*cols;
		for i=1:pixaccount
			statNumber( allNumber( i )+1 )=statNumber( allNumber( i )+1  ) +1;
		end

		CDF=statNumber;
		for i=1:256
			for j=1:i
				CDF(i)=CDF(i)+statNumber(j);
			end
		end
		cdfmin=min(CDF);
        afterBalancePic=zeros(rows,cols);
		%均衡化
        for i=1:rows
            for j=1:cols
                afterBalancePic(i,j)=round(( ( CDF( origin(i,j)+1 ) - cdfmin ) / (pixaccount-cdfmin) )*(L-1));
            end
        end
        
    end

    
    %双三次插值  afterBicubit 返回扩展后图片 %origin 传递灰度图片 scale 扩张系数
    function afterBicubit = Mybicubit(~,origin,scale)
		[rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		afterRows=round(rows*scale);
		afterCols=round(cols*scale);
        
		afterBicubit=ones(afterRows,afterCols);
		temprowstart = origin(1,:); 
		temprowend = origin(rows,:);
		temp_origin = [temprowstart;temprowstart;origin;temprowend;temprowend];
		tempcolstart = temp_origin(:,1); 
		tempcolend = temp_origin(:,cols);
		afterExtendPic = [tempcolstart,tempcolstart,temp_origin,tempcolend,tempcolend];
		%s插值核 卷积
        for i = 1:afterRows
            u= rem(i,scale);
            for j = 1:afterCols
                v=rem(j,scale) ;
				tempi = floor(i/scale)+2; 
				tempj = floor(j/scale)+2;
 				A=[S(u+1),S(u),S(u-1),S(u-2)];
 				B=afterExtendPic(tempi-1:tempi+2,tempj-1:tempj+2);
 				C=[S(v+1);S(v);S(v-1);S(v-2)];
                B=im2double(B);
 				afterBicubit(i,j)=A*B*C;
            end
        end
        
    end
    
    %最近邻插值  afterInterpm 返回扩展后图片 %origin 传递灰度图片 scale 扩张系数
    function afterInterpm = MyInterpm(~,origin,scale)
		[rows,cols,dim]=size(origin);
        if dim>1
            origin=rgb2gray(origin);
        end
		rowsNews=rows*scale;
		colsNews=cols*scale;
		afterInterpm=zeros(rowsNews,colsNews);
        for i = 1 : rowsNews
            for j = 1 : colsNews
                x = round(1/scale*i);
				y = round(1/scale*j);
				afterInterpm(i, j) = origin(x, y);
            end
        end   
    end

        function [] = updateGDT(app)
            app.operatorHistory.Limits=[0,app.index-1];
            app.historyDivi(app.index)=app.index-1;
            app.operatorHistory.MajorTicks=app.historyDivi;
            app.operatorHistory.MajorTickLabels=sprintfc('%g',app.historyDivi);
        end
    end

    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
               app.index=1;
               app.historyDivi(1)=0;
               axis(app.imgShow,'tight');%让图像保持宽高比例填满坐标区
               axis(app.imgShow,'off');%去除坐标区的边框
               app.imgShow.Toolbar.Visible='off';
               %设置禁止为真
               app.FilterOperator.Enable=false;
               app.ColorOperator.Enable=false;
               %默认展示图片
               %app.selectPic=imread("D:\develop\matlab\bin\edgeCo\aaa.png");
               %imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: openFile
        function fileSelect(app, event)
            
            %打开文件
            filter = {'*.jpg';'*.png';'*.jpeg';'*.gif';'*.*'};
            [fileName,pathName]=uigetfile(filter,'选择一个图片');
            str=[pathName fileName];
            if ~isequal(fileName,0) || ~isequal(pathName,0)
                app.ResetPicShow();%清除
                %保存路径
                app.selectPath=str;
                app.infoMessageTextArea.Value=app.selectPath;
                %读取图片
                app.selectPic=imread(str);
                %更新图片
                app.history{app.index}=app.selectPic;
                %设置禁止为假
                %app.FileOperator.Enable=true;
                app.FilterOperator.Enable=true;
                app.ColorOperator.Enable=true;
                %显示图片
                imshow(app.selectPic,'Parent',app.imgShow);
                %设置标题
                app.UIFigure.Name=str;
                %显示历史记录
                app.operatorHistory.Enable=true;
            else
                %用户取消文件选择
                %msgbox('打开文件失败,请重新选择');
           end       
        end

        % Callback function: Exit, UIFigure
        function exit(app, event)
        %退出对话框
        button=questdlg('你确定退出吗？','退出程序','Yes','No','Yes');
        if strcmp(button,'Yes')
             delete(app.UIFigure)
        end
        end

        % Menu selected function: newFile
        function ResetPicShow(app, event)
            %新建被选中
           nullPic=uint8(ones(1,1));
           %重置滚动条
           app.index=1;
           app.historyDivi(1)=0;
           %判断可否删除
           [~,col]=size(app.history);
           app.infoMessageTextArea.Value=num2str(size(app.history));
           %设置禁止为真
           app.FilterOperator.Enable=false;
           app.ColorOperator.Enable=false;
           imshow(nullPic,'Parent',app.imgShow);
           if col>1
               app.history(1,:)=[];%清空
               app.history{1}=nullPic;
               app.operatorHistory.Limits=[0,1];
               app.operatorHistory.MajorTicks=app.historyDivi;
               app.operatorHistory.MajorTickLabels=sprintfc('%g',app.historyDivi);
               app.operatorHistory.Enable=false;
%                %设置禁止为真
%                app.FilterOperator.Enable=false;
%                app.ColorOperator.Enable=false;
%                imshow(nullPic,'Parent',app.imgShow);
           end

        end

        % Menu selected function: saveFile
        function saveFileMenuSelected(app, event)
            %保存当前显示的图片到原路径
               try
                   %exportgraphics(app.imgShow,app.selectPath);
                   imwrite(app.selectPic,app.selectPath);
               catch ME
                   %msgbox(num2str(ME));
                   msgbox("发生错误!请确定有图片数据可以保存!");
               end
        end

        % Menu selected function: gaussian
        function gaussianMenuSelected(app, event)
            app.selectPic=app.MyGaussian(app.selectPic,3);
            %更新历史
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块
            updateGDT(app);
            %显示图片
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: rgb2gray_1
        function rgb2gray_1MenuSelected(app, event)
            %平均值法
            app.tempPic=app.rgb2gray1(app.selectPic);
            if ~isempty(app.tempPic)
                %说明成功了
                app.selectPic=app.tempPic;
                app.index=app.index+1;
                app.history{app.index}=app.selectPic;
                %更新滑块
                updateGDT(app);
                %更新图片
                imshow(app.selectPic,'Parent',app.imgShow);
            end
        end

        % Menu selected function: rgb2gray_4
        function rgb2gray_4Selected(app, event)
            %加权平均值法
            app.tempPic=app.rgb2gray4(app.selectPic);
            if ~isempty(app.tempPic)
                %说明成功了
                app.selectPic=app.tempPic;
                app.index=app.index+1;
                app.history{app.index}=app.selectPic;
                %更新滑块
                updateGDT(app);
                %更新图片
                imshow(app.selectPic,'Parent',app.imgShow);
            end
    
        end

        % Menu selected function: rgb2gray_2
        function rgb2gray_2MenuSelected(app, event)
            %单通道提取法
            %msgbox("输入");
            userAnswer=inputdlg("输入你想要提取的单通道(R=1,G=2,B=3)","输入数值",1,{'1'});
            if isempty(userAnswer) || isempty(str2double(userAnswer{1})) || str2double(userAnswer{1})>3 ||str2double(userAnswer{1})<0
                msgbox("请输入正确的值!");
            else
                %msgbox("输入正确");
                app.tempPic=app.rgb2gray2(app.selectPic,floor(str2double(userAnswer)));
                if ~isempty(app.tempPic)
                    app.selectPic=app.tempPic;
                    app.index=app.index+1;
                    app.history{app.index}=app.selectPic;
                    %更新滑块
                    updateGDT(app);
                    %更新滑块
                    imshow(app.selectPic,'Parent',app.imgShow);
                end
            end
            
            %app.infoMessageTextArea.Value=num2str(userAnswer);
            %if isempty(userAnswer) || 
            
            
%             if isnan(userAnswer) | isempty(userAnswer)
%                 msgbox("请输入一个数字!");
%             else
%                 if (userAnswer>=1) && (userAnswer<=3)
%                     %msgbox("输入正确");
%                     app.tempPic=app.rgb2gray2(app.selectPic,userAnswer);
%                     if ~isempty(app.tempPic)
%                         app.selectPic=app.tempPic;
%                         %更新滑块
%                         updateGDT(app);
%                         %更新滑块
%                         imshow(app.selectPic,'Parent',app.imgShow);
%                     end
%                 else
%                     msgbox("请输入一个正确大小的数字!");
%                 end
%             end
                
            
        end

        % Menu selected function: rgb2gray_3
        function rgb2gray_3MenuSelected(app, event)
            %最大值法
            app.tempPic=uint8(app.rgb2gray3(app.selectPic));
            if ~isempty(app.tempPic)
                app.selectPic=app.tempPic;
                app.index=app.index+1;
                app.history{app.index}=app.selectPic;
                %更新滑块
                updateGDT(app);
                %更新图片
                imshow(app.selectPic,'Parent',app.imgShow);
            end
        end

        % Menu selected function: CannyMenu
        function CannyMenuSelected(app, event)
            %Canny算子
            app.selectPic=app.MyCanny(app.selectPic);
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            msgbox("运算完成!");
            %更新滑块
            updateGDT(app);
            %更新图片
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
           %Sobel算子
           userInputThre=inputdlg("请输入阈值(0-1最好)");
           %判断是否输入
           if ~isempty(userInputThre)
               userInputThre=str2double(userInputThre);
                %判断用户输入是否为数字
                if isnan(userInputThre)
                    msgbox("请输入正确的数字!");
                else
                app.selectPic=app.MySobel(app.selectPic,userInputThre);
                msgbox("运算完成!");
                app.index=app.index+1;
                app.history{app.index}=app.selectPic;
                %更新滑块
                updateGDT(app);
                %更新图片
                imshow(app.selectPic,[],'Parent',app.imgShow);
                end
           else
               %msgbox("取消选择");
           end 
            
        end

        % Menu selected function: LaplaceMenu
        function LaplaceMenuSelected(app, event)
           %laplace算子
           userInputThre=inputdlg("请输入阈值(0-0.05最好)");
           %有值
           if ~isempty(userInputThre)
               userInputThre=str2double(userInputThre);
                %判断用户输入是否为数字
                if isnan(userInputThre)
                    msgbox("请输入正确的数字!");
                else
                    app.selectPic=app.MyLaplace(app.selectPic,userInputThre);
                    msgbox("运算完成!");
                    app.index=app.index+1;
                    app.history{app.index}=app.selectPic;
                    %更新滑块
                    updateGDT(app);
                    %更新图片
                    imshow(app.selectPic,[],'Parent',app.imgShow);
                end
           else
               %msgbox("取消选择");
           end

        end

        % Menu selected function: medianFilter
        function medianFilterMenuSelected(app, event)
            %中值滤波
            app.selectPic=uint8(app.MyMedianFilter(app.selectPic));
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块
            updateGDT(app);
            %更新图片
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: averFilter
        function averFilterMenuSelected(app, event)
             %均值滤波
            app.selectPic=uint8(app.MyAverFilter(app.selectPic));
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块
            updateGDT(app);
            %更新图片
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: balancePic
        function balancePicMenuSelected(app, event)
            %直方图均衡化
            app.selectPic=uint8(app.MyBalancePic(app.selectPic));
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块
            updateGDT(app);
            %更新图片
            imshow(app.selectPic,[],'Parent',app.imgShow);
            %imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: FFT_IFFTH
        function FFT_IFFTHMenuSelected(app, event)
            %傅里叶/反傅里叶高频图像
            [~,~,dim]=size(app.selectPic);
            if dim>1
                app.selectPic=rgb2gray(app.selectPic);
            end
            afterFFT=MyFFT(app.selectPic);
            afterDream_gp=dream_gp(afterFFT);
            pngGP=real(MyIFFT(ifftshift(afterDream_gp)));
            app.selectPic=uint8(pngGP);
            msgbox("运算完成!");
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块
            updateGDT(app);
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: FFT_IFFTL
        function FFT_IFFTLMenuSelected(app, event)
            %傅里叶/反傅里叶低频图像
            [~,~,dim]=size(app.selectPic);
            if dim>1
                app.selectPic=rgb2gray(app.selectPic);
            end
            afterFFT=MyFFT(app.selectPic);
            afterDream_dp=dream_dp(afterFFT);
            pngDP=real(MyIFFT(ifftshift(afterDream_dp)));
            msgbox("运算完成!");
            %app.selectPic=uint8(pngDP);
            app.selectPic=pngDP;
            app.index=app.index+1;
            app.history{app.index}=app.selectPic;
            %更新滑块条
            updateGDT(app);
            imshow(app.selectPic,'Parent',app.imgShow);
        end

        % Menu selected function: interpm
        function interpmMenuSelected(app, event)
          %最近邻插值
          userInputScaleNumber=inputdlg("请输入扩张扩张系数(请不要太大!)");
           %有值
           if ~isempty(userInputScaleNumber)
               userInputScaleNumber=str2double(userInputScaleNumber);
                %判断用户输入是否为数字
                if isnan(userInputScaleNumber)
                    msgbox("请输入正确的数字!");
                else
                    app.selectPic=uint8(app.MyInterpm(app.selectPic,userInputScaleNumber));
                    msgbox("运算完成!");
                    app.index=app.index+1;
                    app.history{app.index}=app.selectPic;
                    %更新滑块
                    updateGDT(app);
                    %更新图片
                    imshow(app.selectPic,'Parent',app.imgShow);
                end
           else
               %msgbox("取消选择");
           end
        end

        % Menu selected function: bicubit
        function bicubitMenuSelected(app, event)
          %双三次插值
          userInputScaleNumber=inputdlg("请输入扩张扩张系数(请不要太大!)");
           %有值
           if ~isempty(userInputScaleNumber)
               userInputScaleNumber=str2double(userInputScaleNumber);
                %判断用户输入是否为数字
                if isnan(userInputScaleNumber)
                    msgbox("请输入正确的数字!");
                else
                    app.selectPic=app.Mybicubit(app.selectPic,userInputScaleNumber);
                    msgbox("运算完成!");
                    app.index=app.index+1;
                    app.history{app.index}=app.selectPic;
                    %更新滑块
                    updateGDT(app);
                    %更新图片
                    imshow(app.selectPic,'Parent',app.imgShow);
                end
           else
               %msgbox("取消选择");
           end
        end

        % Menu selected function: saveOtherFile
        function saveOtherFileMenuSelected(app, event)
            try
                %过滤器
                filter = {'*.jpg';'*.jpeg';'*.png';};
                [fileName, pathName] = uiputfile(filter);
                %判断是否选择
                if ~isequal(fileName,0) || ~isequal(pathName,0)
                    path=[pathName fileName];
                    imwrite(app.selectPic,path);
                end
            catch ME
                msgbox("发生错误,请确定有图片数据可以另存为!")
            end
 
        end

        % Value changed function: operatorHistory
        function operatorHistoryValueChanged(app, event)
            
            [~,col]=size(app.history);
            %app.infoMessageTextArea.Value=num2str(size(app.history));
            if col>1
                %用户改变值
                value = floor(app.operatorHistory.Value);
                %文本框中显示
                app.infoMessageTextArea.Value=num2str(value);
                %显示图片
                if value==0
                    app.selectPic=app.history{1};
                else
                    app.selectPic=app.history{value+1};
                end
                imshow(app.selectPic,'Parent',app.imgShow);
            end

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @exit, true);
            app.FileOperator = uimenu(app.UIFigure);
            app.FileOperator.Text = '文件';
            app.newFile = uimenu(app.FileOperator);
            app.newFile.MenuSelectedFcn = createCallbackFcn(app, @ResetPicShow, true);
            app.newFile.Text = '新建';
            app.openFile = uimenu(app.FileOperator);
            app.openFile.MenuSelectedFcn = createCallbackFcn(app, @fileSelect, true);
            app.openFile.Text = '打开文件';
            app.saveFile = uimenu(app.FileOperator);
            app.saveFile.MenuSelectedFcn = createCallbackFcn(app, @saveFileMenuSelected, true);
            app.saveFile.Text = '保存文件';
            app.saveOtherFile = uimenu(app.FileOperator);
            app.saveOtherFile.MenuSelectedFcn = createCallbackFcn(app, @saveOtherFileMenuSelected, true);
            app.saveOtherFile.Text = '另存为';
            app.Exit = uimenu(app.FileOperator);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @exit, true);
            app.Exit.Text = '退出';
            app.FilterOperator = uimenu(app.UIFigure);
            app.FilterOperator.Text = '滤镜';
            app.gaussian = uimenu(app.FilterOperator);
            app.gaussian.MenuSelectedFcn = createCallbackFcn(app, @gaussianMenuSelected, true);
            app.gaussian.Separator = 'on';
            app.gaussian.Text = '高斯模糊';
            app.edgeOperator = uimenu(app.FilterOperator);
            app.edgeOperator.Separator = 'on';
            app.edgeOperator.Text = '边缘检测';
            app.CannyMenu = uimenu(app.edgeOperator);
            app.CannyMenu.MenuSelectedFcn = createCallbackFcn(app, @CannyMenuSelected, true);
            app.CannyMenu.Text = 'Canny';
            app.SobelMenu = uimenu(app.edgeOperator);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.LaplaceMenu = uimenu(app.edgeOperator);
            app.LaplaceMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplaceMenuSelected, true);
            app.LaplaceMenu.Text = 'Laplace';
            app.filteringOperator = uimenu(app.FilterOperator);
            app.filteringOperator.Separator = 'on';
            app.filteringOperator.Text = '滤波操作';
            app.medianFilter = uimenu(app.filteringOperator);
            app.medianFilter.MenuSelectedFcn = createCallbackFcn(app, @medianFilterMenuSelected, true);
            app.medianFilter.Text = '中值滤波';
            app.averFilter = uimenu(app.filteringOperator);
            app.averFilter.MenuSelectedFcn = createCallbackFcn(app, @averFilterMenuSelected, true);
            app.averFilter.Text = '均值滤波';
            app.FFTOperator = uimenu(app.FilterOperator);
            app.FFTOperator.Separator = 'on';
            app.FFTOperator.Text = '傅里叶变换';
            app.FFT_IFFTH = uimenu(app.FFTOperator);
            app.FFT_IFFTH.MenuSelectedFcn = createCallbackFcn(app, @FFT_IFFTHMenuSelected, true);
            app.FFT_IFFTH.Text = '傅里叶/反傅里叶高频';
            app.FFT_IFFTL = uimenu(app.FFTOperator);
            app.FFT_IFFTL.MenuSelectedFcn = createCallbackFcn(app, @FFT_IFFTLMenuSelected, true);
            app.FFT_IFFTL.Text = '傅里叶/反傅里叶低频';
            app.expandOperator = uimenu(app.FilterOperator);
            app.expandOperator.Separator = 'on';
            app.expandOperator.Text = '图片扩张';
            app.interpm = uimenu(app.expandOperator);
            app.interpm.MenuSelectedFcn = createCallbackFcn(app, @interpmMenuSelected, true);
            app.interpm.Text = '最近邻插值';
            app.bicubit = uimenu(app.expandOperator);
            app.bicubit.MenuSelectedFcn = createCallbackFcn(app, @bicubitMenuSelected, true);
            app.bicubit.Text = '双三次插值';
            app.picPlus = uimenu(app.FilterOperator);
            app.picPlus.Separator = 'on';
            app.picPlus.Text = '图像增强';
            app.balancePic = uimenu(app.picPlus);
            app.balancePic.MenuSelectedFcn = createCallbackFcn(app, @balancePicMenuSelected, true);
            app.balancePic.Text = '直方图均衡化';
            app.ColorOperator = uimenu(app.UIFigure);
            app.ColorOperator.Text = '色彩';
            app.rgb2gray_first = uimenu(app.ColorOperator);
            app.rgb2gray_first.Separator = 'on';
            app.rgb2gray_first.Text = '灰度转换';
            app.rgb2gray_1 = uimenu(app.rgb2gray_first);
            app.rgb2gray_1.MenuSelectedFcn = createCallbackFcn(app, @rgb2gray_1MenuSelected, true);
            app.rgb2gray_1.Text = '平均值法';
            app.rgb2gray_2 = uimenu(app.rgb2gray_first);
            app.rgb2gray_2.MenuSelectedFcn = createCallbackFcn(app, @rgb2gray_2MenuSelected, true);
            app.rgb2gray_2.Text = '单通道提取(分量法)';
            app.rgb2gray_3 = uimenu(app.rgb2gray_first);
            app.rgb2gray_3.MenuSelectedFcn = createCallbackFcn(app, @rgb2gray_3MenuSelected, true);
            app.rgb2gray_3.Text = '最大值法';
            app.rgb2gray_4 = uimenu(app.rgb2gray_first);
            app.rgb2gray_4.MenuSelectedFcn = createCallbackFcn(app, @rgb2gray_4Selected, true);
            app.rgb2gray_4.Text = '加权平均值法';
            app.imgShow = uiaxes(app.UIFigure);
            app.imgShow.XTick = [];
            app.imgShow.YTick = [];
            app.infoMessageTextAreaLabel = uilabel(app.UIFigure);
            app.infoMessageTextAreaLabel.Text = {'infoMessage'; ''};
            app.infoMessageTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'历史记录'; ''};
            app.operatorHistory = uislider(app.UIFigure);
            app.operatorHistory.Limits = [0 1];
            app.operatorHistory.MajorTicks = 0;
            app.operatorHistory.MajorTickLabels = {'0'};
            app.operatorHistory.ValueChangedFcn = createCallbackFcn(app, @operatorHistoryValueChanged, true);
            app.operatorHistory.MinorTicks = [];
            app.operatorHistory.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end